classdef slice_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SlicerUIFigure                matlab.ui.Figure
        SliceSliderX                  matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
        LoadDataPanel                 matlab.ui.container.Panel
        DatafileEditFieldLabel        matlab.ui.control.Label
        DatafileEditField             matlab.ui.control.EditField
        OpenFoldersButton             matlab.ui.control.Button
        PlotParametersPanel           matlab.ui.container.Panel
        NumberofisolinesSpinnerLabel  matlab.ui.control.Label
        NumberofisolinesSpinner       matlab.ui.control.Spinner
        ViewSpinnerLabel              matlab.ui.control.Label
        spinner_az                    matlab.ui.control.Spinner
        spinner_el                    matlab.ui.control.Spinner
        xsliceSwitchLabel             matlab.ui.control.Label
        xsliceSwitch                  matlab.ui.control.Switch
        ysliceSwitchLabel             matlab.ui.control.Label
        ysliceSwitch                  matlab.ui.control.Switch
        zsliceSwitchLabel             matlab.ui.control.Label
        zsliceSwitch                  matlab.ui.control.Switch
        ColormapDropDownLabel         matlab.ui.control.Label
        ColormapDropDown              matlab.ui.control.DropDown
        SetdataPanel                  matlab.ui.container.Panel
        xcolumnEditFieldLabel         matlab.ui.control.Label
        xcolumnField                  matlab.ui.control.NumericEditField
        ycolumnEditFieldLabel         matlab.ui.control.Label
        ycolumnField                  matlab.ui.control.NumericEditField
        zcolumnEditFieldLabel         matlab.ui.control.Label
        zcolumnField                  matlab.ui.control.NumericEditField
        fcolumnEditFieldLabel         matlab.ui.control.Label
        fcolumnField                  matlab.ui.control.NumericEditField
        SliceSliderY                  matlab.ui.control.Slider
        SliceSliderZ                  matlab.ui.control.Slider
    end


    properties (Access = private)
        c = [1 2 3 4]; % default columns to plot
        data = [];
        
        max_col = 4;
        
        x = []; y = []; z = [];
        nx = []; ny = []; nz = [];
        minx = []; maxx = [];
        miny = []; maxy = [];
        minz = []; maxz = [];
        
        f = [];
        
        data_file = [];
        
        n_iso = 50;
        
        slice_val_x = 0.5;
        slice_val_y = [];
        slice_val_z = [];
        
        vp = struct('az',24.8,'el', 46.0);
        
    end

    
    methods (Access = private)


        function load_data_from_file( app )
            
            app.data = load( app.data_file);
            
            app.max_col = size(app.data,2);
            
            if(  app.max_col < 4 )
                uialert(app.SlicerUIFigure,'Data file should have at least 4 columns','Error'); 
                return
            end
            
            set_arrays( app );    

        end


        function set_arrays( app )
            
            xx = sort( unique( app.data(:,app.c(1)) ) );
            yy = sort( unique( app.data(:,app.c(2)) ) );
            zz = sort( unique( app.data(:,app.c(3)) ) );


            app.minx = min(xx); app.maxx = max(xx);
            app.miny = min(yy); app.maxy = max(yy);
            app.minz = min(zz); app.maxz = max(zz);
            
            app.nx = length(xx);
            app.ny = length(yy);
            app.nz = length(zz);
            
            [app.x, app.y,app.z] = meshgrid( xx, yy, zz );  

            app.f = reshape( app.data(:,app.c(4)), [app.nx app.ny app.nz]);
            app.f = permute( app.f, [2 3 1] );
        end


        function plot_slice( app )
            
            cla( app.UIAxes );
                        
            hold( app.UIAxes, 'on');
            
            
            h = slice( app.UIAxes,  app.x, app.y, app.z, app.f, app.slice_val_x,app.slice_val_y, app.slice_val_z );
            h2 = contourslice( app.UIAxes, app.x, app.y, app.z, app.f , app.slice_val_x, app.slice_val_y, app.slice_val_z, app.n_iso );
            
            colormap(app.UIAxes)
            
            set(findobj(h2),'EdgeColor',[0 0 0]);
            
            hold( app.UIAxes, 'off');
            
            set( findall( h , '-property', 'EdgeColor'), 'EdgeColor', 'none');
            set( findall( h , '-property', 'FaceColor'), 'FaceColor', 'interp')
            set( findall( h , '-property', 'DiffuseStrength'), 'DiffuseStrength', 1)
            
            
            xlim(app.UIAxes,[app.minx app.maxx]);
            ylim(app.UIAxes,[app.miny app.maxy]);
            zlim(app.UIAxes,[app.minz app.maxz]);
            
            view( app.UIAxes, [ app.vp.az   app.vp.el] )
            
            
            colorbar( app.UIAxes )
            
            rotate3d(  app.UIAxes, 'on' );

            drawnow;
            
        end


        function flag = open_folder_button( app )
            
            cla( app.UIAxes );
            app.data = [];
            app.SliceSliderX.Enable='off';
            app.NumberofisolinesSpinner.Enable = 'off';
            
            [file,path] = uigetfile({'*.dat','*.txt'},'Select a File');

            if( exist( fullfile(path,file), 'file') == 0 )
                uialert(app.SlicerUIFigure,'Data file not found','Error');
                flag = 0;
            else
                app.data_file = fullfile(path,file);
                app.DatafileEditField.Value = file;
                load_data_from_file(app);
                app.SliceSliderX.Enable='on';
                app.NumberofisolinesSpinner.Enable = 'on';
                flag = 1;
            end
        
        end
   
        
        function flag = open_folder_text( app )
            
            cla( app.UIAxes );
            app.data = [];
            app.SliceSliderX.Enable='off';
            app.NumberofisolinesSpinner.Enable = 'off';
            
            value = app.DatafileEditField.Value;

            if( exist( value, 'file') == 0 )
                uialert(app.SlicerUIFigure,'Data file not found','Error');
                flag = 0;
            else
                app.data_file = value;
                load_data_from_file(app);
                app.SliceSliderX.Enable='on';
                app.NumberofisolinesSpinner.Enable = 'on';
                flag = 1;
            end
        
        end


        function changed = set_column( app, k)
            switch k
                case 1
                    value = app.xcolumnField.Value;
                case 2
                    value = app.ycolumnField.Value;
                case 3
                    value = app.zcolumnField.Value;
                case 4
                    value = app.fcolumnField.Value;
            end
            
            if( value > app.max_col )
                changed = 0;
                str = sprintf('Maximum column must be less or equal to %d',app.max_col);
                uialert(app.SlicerUIFigure, str ,'Error');
                return;
            else
                app.c(k) = value;
                changed  = 1;
            end
        end
        
        
        function plot_parameters_off(app)
           set( findall( app.PlotParametersPanel.Children , '-property', 'Enable'), 'Enable', 'off')
        end
        
        function plot_parameters_on(app)
           set( findall( app.PlotParametersPanel.Children , '-property', 'Enable'), 'Enable', 'on')
        end
        
        function set_columns_off(app)
           set( findall( app.SetdataPanel.Children , '-property', 'Enable'), 'Enable', 'off')
        end
        
        function set_columns_on(app)
           set( findall( app.SetdataPanel.Children , '-property', 'Enable'), 'Enable', 'on')
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            set_columns_off(app);
            plot_parameters_off(app);
        end

        % Button pushed function: OpenFoldersButton
        function OpenFoldersButtonPushed(app, event)
            set_columns_off(app);
            plot_parameters_off(app);
            success=open_folder_button( app );
            if( success )
                plot_slice( app )
                set_columns_on(app)
                plot_parameters_on(app)
            end
        end

        % Value changed function: DatafileEditField
        function DatafileEditFieldValueChanged(app, event)
            set_columns_off(app);
            plot_parameters_off(app);
            success = open_folder_text( app );
            if( success )
                plot_slice( app )
                set_columns_on(app)
                plot_parameters_on(app)
            end
        end

        % Value changed function: SliceSliderX
        function SliceSliderXValueChanged(app, event)
            app.slice_val_x = app.SliceSliderX.Value;
            plot_slice( app )
        end

        % Value changed function: NumberofisolinesSpinner
        function NumberofisolinesSpinnerValueChanged(app, event)
            app.n_iso = app.NumberofisolinesSpinner.Value;
            plot_slice( app )
        end

        % Value changing function: NumberofisolinesSpinner
        function NumberofisolinesSpinnerValueChanging(app, event)
            app.n_iso = event.Value;
            plot_slice( app )
        end

        % Value changing function: SliceSliderX
        function SliceSliderXValueChanging(app, event)
            app.slice_val_x = event.Value;
            plot_slice( app )
        end

        % Value changed function: spinner_az
        function spinner_azValueChanged(app, event)
            app.vp.az = app.spinner_az.Value;
            plot_slice( app )
        end

        % Value changing function: spinner_az
        function spinner_azValueChanging(app, event)
            app.vp.az = event.Value;
            plot_slice( app )
        end

        % Value changed function: spinner_el
        function spinner_elValueChanged(app, event)
            app.vp.el = app.spinner_el.Value;
            plot_slice( app )
        end

        % Value changing function: spinner_el
        function spinner_elValueChanging(app, event)
            app.vp.el = event.Value;
            plot_slice( app )
        end

        % Value changed function: xcolumnField
        function xcolumnFieldValueChanged(app, event)
            changed = set_column( app, 1);
            if( changed )
                set_arrays( app );
                plot_slice( app )
            end
        end

        % Value changed function: ycolumnField
        function ycolumnFieldValueChanged(app, event)
            changed = set_column( app, 2);
            if( changed )
                set_arrays( app );
                plot_slice( app )
            end
        end

        % Value changed function: zcolumnField
        function zcolumnFieldValueChanged(app, event)
            changed = set_column( app, 3);
            if( changed )
                set_arrays( app );
                plot_slice( app )
            end
        end

        % Value changed function: fcolumnField
        function fcolumnFieldValueChanged(app, event)
            changed = set_column( app, 4);
            if( changed )
                set_arrays( app );
                plot_slice( app )
            end
        end

        % Value changed function: ysliceSwitch
        function ysliceSwitchValueChanged(app, event)
            value = app.ysliceSwitch.Value;
            if( strcmp(value,'On') )
                app.SliceSliderY.Enable = 'On';
                app.slice_val_y = app.SliceSliderY.Value;
            else
                app.SliceSliderY.Enable = 'Off';
                app.slice_val_y = [];
            end
            plot_slice( app )
        end

        % Value changed function: SliceSliderY
        function SliceSliderYValueChanged(app, event)
            app.slice_val_y = app.SliceSliderY.Value;
            plot_slice( app )             
        end

        % Value changing function: SliceSliderY
        function SliceSliderYValueChanging(app, event)
            app.slice_val_y = event.Value;
            plot_slice( app )
        end

        % Value changed function: SliceSliderZ
        function SliceSliderZValueChanged(app, event)
            app.slice_val_z = app.SliceSliderZ.Value;
            plot_slice( app ) 
        end

        % Value changing function: SliceSliderZ
        function SliceSliderZValueChanging(app, event)
            app.slice_val_z = event.Value;
            plot_slice( app )
        end

        % Value changed function: zsliceSwitch
        function zsliceSwitchValueChanged(app, event)
            value = app.zsliceSwitch.Value;
            if( strcmp(value,'On') )
                app.SliceSliderZ.Enable = 'On';
                app.slice_val_z = app.SliceSliderZ.Value;
            else
                app.SliceSliderZ.Enable = 'Off';
                app.slice_val_z = [];
            end
            plot_slice( app )
        end

        % Value changed function: xsliceSwitch
        function xsliceSwitchValueChanged(app, event)
            value = app.xsliceSwitch.Value;
            if( strcmp(value,'On') )
                app.SliceSliderX.Enable = 'On';
                app.slice_val_x = app.SliceSliderX.Value;
            else
                app.SliceSliderX.Enable = 'Off';
                app.slice_val_x = [];
            end
            plot_slice( app )
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            colormap( app.UIAxes, app.ColormapDropDown.Value )
            plot_slice( app )
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SlicerUIFigure = uifigure;
            app.SlicerUIFigure.Name = 'Slicer';
            app.SliceSliderX = uislider(app.SlicerUIFigure);
            app.SliceSliderX.Limits = [0 1];
            app.SliceSliderX.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliceSliderX.ValueChangedFcn = createCallbackFcn(app, @SliceSliderXValueChanged, true);
            app.SliceSliderX.ValueChangingFcn = createCallbackFcn(app, @SliceSliderXValueChanging, true);
            app.SliceSliderX.Enable = 'off';
            app.SliceSliderX.Value = 0.5;
            app.UIAxes = uiaxes(app.SlicerUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.LoadDataPanel = uipanel(app.SlicerUIFigure);
            app.LoadDataPanel.Title = 'Load Data';
            app.DatafileEditFieldLabel = uilabel(app.LoadDataPanel);
            app.DatafileEditFieldLabel.Text = 'Data file';
            app.DatafileEditField = uieditfield(app.LoadDataPanel, 'text');
            app.DatafileEditField.ValueChangedFcn = createCallbackFcn(app, @DatafileEditFieldValueChanged, true);
            app.OpenFoldersButton = uibutton(app.LoadDataPanel, 'push');
            app.OpenFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFoldersButtonPushed, true);
            app.OpenFoldersButton.Icon = 'Folder-Open-icon.png';
            app.OpenFoldersButton.Text = '';
            app.PlotParametersPanel = uipanel(app.SlicerUIFigure);
            app.PlotParametersPanel.Title = 'Plot Parameters';
            app.NumberofisolinesSpinnerLabel = uilabel(app.PlotParametersPanel);
            app.NumberofisolinesSpinnerLabel.Text = 'Number of isolines';
            app.NumberofisolinesSpinner = uispinner(app.PlotParametersPanel);
            app.NumberofisolinesSpinner.ValueChangingFcn = createCallbackFcn(app, @NumberofisolinesSpinnerValueChanging, true);
            app.NumberofisolinesSpinner.Limits = [0 200];
            app.NumberofisolinesSpinner.RoundFractionalValues = 'on';
            app.NumberofisolinesSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofisolinesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofisolinesSpinnerValueChanged, true);
            app.NumberofisolinesSpinner.Value = 50;
            app.ViewSpinnerLabel = uilabel(app.PlotParametersPanel);
            app.ViewSpinnerLabel.Text = 'View';
            app.spinner_az = uispinner(app.PlotParametersPanel);
            app.spinner_az.ValueChangingFcn = createCallbackFcn(app, @spinner_azValueChanging, true);
            app.spinner_az.Limits = [0 360];
            app.spinner_az.ValueChangedFcn = createCallbackFcn(app, @spinner_azValueChanged, true);
            app.spinner_az.Value = 24.8;
            app.spinner_el = uispinner(app.PlotParametersPanel);
            app.spinner_el.ValueChangingFcn = createCallbackFcn(app, @spinner_elValueChanging, true);
            app.spinner_el.Limits = [0 360];
            app.spinner_el.ValueChangedFcn = createCallbackFcn(app, @spinner_elValueChanged, true);
            app.spinner_el.Value = 46;
            app.xsliceSwitchLabel = uilabel(app.PlotParametersPanel);
            app.xsliceSwitchLabel.Text = 'x-slice';
            app.xsliceSwitch = uiswitch(app.PlotParametersPanel, 'slider');
            app.xsliceSwitch.ValueChangedFcn = createCallbackFcn(app, @xsliceSwitchValueChanged, true);
            app.xsliceSwitch.Value = 'On';
            app.ysliceSwitchLabel = uilabel(app.PlotParametersPanel);
            app.ysliceSwitchLabel.Text = 'y-slice';
            app.ysliceSwitch = uiswitch(app.PlotParametersPanel, 'slider');
            app.ysliceSwitch.ValueChangedFcn = createCallbackFcn(app, @ysliceSwitchValueChanged, true);
            app.zsliceSwitchLabel = uilabel(app.PlotParametersPanel);
            app.zsliceSwitchLabel.Text = 'z-slice';
            app.zsliceSwitch = uiswitch(app.PlotParametersPanel, 'slider');
            app.zsliceSwitch.ValueChangedFcn = createCallbackFcn(app, @zsliceSwitchValueChanged, true);
            app.ColormapDropDownLabel = uilabel(app.PlotParametersPanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.PlotParametersPanel);
            app.ColormapDropDown.Items = {'winter', 'spring', 'hot', 'hsv', 'jet', 'parula'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'winter';
            app.SetdataPanel = uipanel(app.SlicerUIFigure);
            app.SetdataPanel.Title = 'Set data';
            app.xcolumnEditFieldLabel = uilabel(app.SetdataPanel);
            app.xcolumnEditFieldLabel.Text = 'x = column';
            app.xcolumnField = uieditfield(app.SetdataPanel, 'numeric');
            app.xcolumnField.Limits = [1 Inf];
            app.xcolumnField.ValueChangedFcn = createCallbackFcn(app, @xcolumnFieldValueChanged, true);
            app.xcolumnField.Value = 1;
            app.ycolumnEditFieldLabel = uilabel(app.SetdataPanel);
            app.ycolumnEditFieldLabel.Text = 'y = column';
            app.ycolumnField = uieditfield(app.SetdataPanel, 'numeric');
            app.ycolumnField.Limits = [1 Inf];
            app.ycolumnField.ValueChangedFcn = createCallbackFcn(app, @ycolumnFieldValueChanged, true);
            app.ycolumnField.Value = 2;
            app.zcolumnEditFieldLabel = uilabel(app.SetdataPanel);
            app.zcolumnEditFieldLabel.Text = 'z = column';
            app.zcolumnField = uieditfield(app.SetdataPanel, 'numeric');
            app.zcolumnField.Limits = [1 Inf];
            app.zcolumnField.ValueChangedFcn = createCallbackFcn(app, @zcolumnFieldValueChanged, true);
            app.zcolumnField.Value = 3;
            app.fcolumnEditFieldLabel = uilabel(app.SetdataPanel);
            app.fcolumnEditFieldLabel.Text = 'f = column';
            app.fcolumnField = uieditfield(app.SetdataPanel, 'numeric');
            app.fcolumnField.Limits = [1 Inf];
            app.fcolumnField.ValueChangedFcn = createCallbackFcn(app, @fcolumnFieldValueChanged, true);
            app.fcolumnField.Value = 4;
            app.SliceSliderY = uislider(app.SlicerUIFigure);
            app.SliceSliderY.Limits = [0 1];
            app.SliceSliderY.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliceSliderY.ValueChangedFcn = createCallbackFcn(app, @SliceSliderYValueChanged, true);
            app.SliceSliderY.ValueChangingFcn = createCallbackFcn(app, @SliceSliderYValueChanging, true);
            app.SliceSliderY.Enable = 'off';
            app.SliceSliderY.Value = 0.5;
            app.SliceSliderZ = uislider(app.SlicerUIFigure);
            app.SliceSliderZ.Limits = [0 1];
            app.SliceSliderZ.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliceSliderZ.ValueChangedFcn = createCallbackFcn(app, @SliceSliderZValueChanged, true);
            app.SliceSliderZ.ValueChangingFcn = createCallbackFcn(app, @SliceSliderZValueChanging, true);
            app.SliceSliderZ.Enable = 'off';
            app.SliceSliderZ.Value = 0.5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = slice_plot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SlicerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SlicerUIFigure)
        end
    end
end